classdef TareasignalCanvas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CheckBox_3           matlab.ui.control.CheckBox
        NombreCheckBox       matlab.ui.control.CheckBox
        RejillaCheckBox      matlab.ui.control.CheckBox
        PushbuttonButton     matlab.ui.control.Button
        KeyboardinputButton  matlab.ui.control.Button
        VRMButton            matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: VRMButton
        function VRMButtonPushed(app, event)
            cla(app.UIAxes,"reset")
            t = 0:pi/12:1.5*pi;
            x = sawtooth(2*pi*100*t)+1;
            x1 = cos(t+pi)+1;
            z=x.*x1;
            plot(app.UIAxes,z)

        end

        % Button pushed function: KeyboardinputButton
        function KeyboardinputButtonPushed(app, event)
            cla(app.UIAxes,"reset")
            frecuencia = 100;
            f = 2;
            tiempo = 0: 1/frecuencia:1-(1/frecuencia);
            yAuxiliar = 5*square(2*pi*2*tiempo)+0.05*randn(size(tiempo));
            y = (yAuxiliar +5)/2;
            plot(app.UIAxes,y)
        end

        % Button pushed function: PushbuttonButton
        function PushbuttonButtonPushed(app, event)
            cla(app.UIAxes,"reset")
            x = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19]
            y = [0 1 1 0 0 1 1 1 0 1 0 1 1 0 0 0 1 1 1 0]
            plot(app.UIAxes,x,y)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señales')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VRMButton = uibutton(app.UIFigure, 'push');
            app.VRMButton.ButtonPushedFcn = createCallbackFcn(app, @VRMButtonPushed, true);
            app.VRMButton.Text = 'VRM';
            app.KeyboardinputButton = uibutton(app.UIFigure, 'push');
            app.KeyboardinputButton.ButtonPushedFcn = createCallbackFcn(app, @KeyboardinputButtonPushed, true);
            app.KeyboardinputButton.Text = 'Keyboard input';
            app.PushbuttonButton = uibutton(app.UIFigure, 'push');
            app.PushbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @PushbuttonButtonPushed, true);
            app.PushbuttonButton.Text = 'Push button';
            app.RejillaCheckBox = uicheckbox(app.UIFigure);
            app.RejillaCheckBox.Text = 'Rejilla';
            app.NombreCheckBox = uicheckbox(app.UIFigure);
            app.NombreCheckBox.Text = 'Nombre';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TareasignalCanvas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end